classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ChooseTransmitTypeButtonGroup  matlab.ui.container.ButtonGroup
        HexStringButton                matlab.ui.control.RadioButton
        CommandButton                  matlab.ui.control.RadioButton
        CommandDropDown                matlab.ui.control.DropDown
        CommandDropDownLabel           matlab.ui.control.Label
        ASKDropDown                    matlab.ui.control.DropDown
        ASKDropDownLabel               matlab.ui.control.Label
        InvalidHexLabel                matlab.ui.control.Label
        SampleRateField                matlab.ui.control.NumericEditField
        EnterSampleRateinMHzEditFieldLabel  matlab.ui.control.Label
        HexStringField                 matlab.ui.control.EditField
        EnterHexStringLabel            matlab.ui.control.Label
        TransmitButton                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TransmitButton
        function TransmitButtonPushed(app, event)
            %%
            % Transmits data from Hex String Field
            % at chosen sample rate
            app.InvalidHexLabel.Visible = false;
            fs = app.SampleRateField.Value * 10^6;
            %Checks whether to send a command or hex string
            if app.CommandButton.Value
                hexString = app.CommandDropDown.Value;
            else
                hexString = app.HexStringField.Value;
            end
            %Check for a valid hex string and then transmit
            try
                usrpTransmit(hexString, fs,app.ASKDropDown.Value);
            catch ME
                %Check if Hex String is valid characters
                if strcmp(ME.identifier, 'daq:general:invalidHexCharacters') || strcmp(ME.identifier, 'daq:general:needsHexNumber')
                    % Handle error
                    app.InvalidHexLabel.Visible = true;
                else
                    % If it's a different error, rethrow
                    rethrow(ME);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TransmitButton = uibutton(app.UIFigure, 'push');
            app.TransmitButton.ButtonPushedFcn = createCallbackFcn(app, @TransmitButtonPushed, true);
            app.TransmitButton.Text = 'Transmit';
            app.EnterHexStringLabel = uilabel(app.UIFigure);
            app.EnterHexStringLabel.Text = 'Enter Hex String:';
            app.HexStringField = uieditfield(app.UIFigure, 'text');
            app.HexStringField.Value = '9040007360';
            app.EnterSampleRateinMHzEditFieldLabel = uilabel(app.UIFigure);
            app.EnterSampleRateinMHzEditFieldLabel.Text = 'Enter Sample Rate (in MHz):';
            app.SampleRateField = uieditfield(app.UIFigure, 'numeric');
            app.SampleRateField.ValueDisplayFormat = '%.3f';
            app.SampleRateField.Value = 13.242;
            app.InvalidHexLabel = uilabel(app.UIFigure);
            app.InvalidHexLabel.Visible = 'off';
            app.InvalidHexLabel.Text = 'Invalid Hex String';
            app.ASKDropDownLabel = uilabel(app.UIFigure);
            app.ASKDropDownLabel.Text = 'ASK %:';
            app.ASKDropDown = uidropdown(app.UIFigure);
            app.ASKDropDown.Items = {'10%', '15%', '20%', '25%', '30%', '100%'};
            app.ASKDropDown.ItemsData = [0.1 0.15 0.2 0.25 0.3 1];
            app.ASKDropDown.Value = 0.3;
            app.CommandDropDownLabel = uilabel(app.UIFigure);
            app.CommandDropDownLabel.Text = 'Command';
            app.CommandDropDown = uidropdown(app.UIFigure);
            app.CommandDropDown.Items = {'Inventory', 'Reset to Ready', 'System Information'};
            app.CommandDropDown.ItemsData = {'9040007360', '80987360', '80E87360'};
            app.CommandDropDown.Value = '9040007360';
            app.ChooseTransmitTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseTransmitTypeButtonGroup.Title = 'Choose Transmit Type:';
            app.CommandButton = uiradiobutton(app.ChooseTransmitTypeButtonGroup);
            app.CommandButton.Text = 'Command';
            app.CommandButton.Value = true;
            app.HexStringButton = uiradiobutton(app.ChooseTransmitTypeButtonGroup);
            app.HexStringButton.Text = 'Hex String';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end